classdef aplicacionmatlab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParmetrosdecaracterizacinLabel  matlab.ui.control.Label
        ndicesdedesempeoLabel           matlab.ui.control.Label
        GuardarButton                   matlab.ui.control.Button
        WdLabel                         matlab.ui.control.Label
        ITSETextArea                    matlab.ui.control.TextArea
        WdTextArea                      matlab.ui.control.TextArea
        ITAETextArea                    matlab.ui.control.TextArea
        ISETextArea                     matlab.ui.control.TextArea
        ITAELabel                       matlab.ui.control.Label
        ITSELabel                       matlab.ui.control.Label
        ISELabel                        matlab.ui.control.Label
        TrLabel                         matlab.ui.control.Label
        TrTextArea                      matlab.ui.control.TextArea
        TsTextArea                      matlab.ui.control.TextArea
        TsLabel                         matlab.ui.control.Label
        TpTextArea                      matlab.ui.control.TextArea
        TpLabel                         matlab.ui.control.Label
        WnTextArea                      matlab.ui.control.TextArea
        WnLabel                         matlab.ui.control.Label
        AmortiguamientoTextArea         matlab.ui.control.TextArea
        AmortiguamientoLabel            matlab.ui.control.Label
        TauTextArea                     matlab.ui.control.TextArea
        tauLabel                        matlab.ui.control.Label
        TmTextArea                      matlab.ui.control.TextArea
        TmLabel                         matlab.ui.control.Label
        KTextArea                       matlab.ui.control.TextArea
        KLabel                          matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        TipoTextArea                    matlab.ui.control.TextArea
        TipoTextAreaLabel               matlab.ui.control.Label
        EscojaelmetodoquedeseesusarLabel  matlab.ui.control.Label
        FuncindetransferenciaLabel      matlab.ui.control.Label
        EjercutarButton                 matlab.ui.control.StateButton
        CargarButton                    matlab.ui.control.StateButton
        TextArea                        matlab.ui.control.TextArea
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CargarButton
        function CargarButtonValueChanged(app, event)
        global t y;
        %1.cargando el archivo
        [file, path]=uigetfile;       
        data=load([path,file]);
        data=struct2array(data);
        %2.visualizar respuesta
        plot(app.UIAxes, data(:,1), data(:,2));
        t=data(:,1);
        y=data(:,2);
        %3.definiendo si la respuesta es subamortiguada o amortiguada
        if max(y)>y(end)
        set(app.TipoTextArea,'Value','Sistema Subamortiguado');
        else
        set(app.TipoTextArea,'Value','Sistema Sobreamortiguado');       
        end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
                          
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;

            
        end

        % Value changed function: EjercutarButton
        function EjercutarButtonValueChanged(app, event)
            value = app.EjercutarButton.Value;
            global t y FT t1i t2i Vector t1 t2 F_1 t3 t4 t5 wn t3i mp t4i wd Pico sigma Asentamiento Retardo f2 ITAE t6 t6i f3 f1 ITSE ISE FT_2 t5i FT_str2 n cond t28 t28i t63 t63i coefamort tau tm num den FT_str1 fr  FT_str FT_1;
            V=get(app.ListBox,'Value');
            if strcmp(V, 'Alfaro')
                %METODO DE LOS DOS PUNTOS GENERAL USANDO CONSTANTES
                %DE ALFARO(SOBREAMORTIGUADOS)
                t1=0.25*y(end);
                t2=0.75*y(end);                   
                t1i=interp1(y,t,t1);
                t2i=interp1(y,t,t2);
                %Constantes de Alfaro
                a=-0.910;b=0.910;c=1.262;d=-0.262;
                tau=a*t1i+b*t2i;
                tm=c*t1i+d*t2i;
                %funcion de transferencia         
                num=y(end);
                den=[tau 1];
                FT=tf(num,den,'InputDeLay',tm);
                FT_str=evalc('FT');           
            end 
            if strcmp(V,'Jahamiri y Fallahi')
                %METODO DE JAHANMIRI Y FALLAHI SUBAMORTIGUADOS SEGUNDO
                %ORDEN
                t3=0.05*y(end);
                t4=0.70*y(end);
                t5=0.90*y(end);
                t6=0.02*y(end);
                t3i=interp1(y,t,t3);
                t4i=interp1(y,t,t4);
                t5i=interp1(y,t,t5);
                t6i=interp1(y,t,t6);
                n=(t5i-t4i)/(t5i-t6i);
                cond= n<=0.4771;
                if cond 
                    sigma=sqrt((0.4844651-(0.75323499.*n))/(1-2.0946444.*n));
                else
                    sigma=13.9352;               
                end
                tau=(t5i-t6i)/((0.424301)+(4.62533.*sigma)-(2.65412*exp(-sigma)));
                tm=t6i;             
                %funcion de transferencia
                num=(y(end));
                den=[tau^2 (2.*sigma.*tau) 1];
                FT=tf((y(end)),[tau.^(2) (2.*sigma.*tau) 1],'InputDeLay',tm);
                FT_str=evalc('FT');           
            end
            if strcmp(V,'Smith')
                %METODO DE SMITH PRIMER ORDEN
                t28=y(end)*0.283;
                t63=y(end)*0.632;
                t28i=interp1(y,t,t28);
                t63i=interp1(y,t,t63);
                tau=1.5*(t63i-t28i);
                fr=1/tau;
                tm=(t63i-tau);
                num=y(end);
                den=[tau 1];
                FT=tf(num,den,'InputDeLay',tm);
                FT_str=evalc('FT');
            end
            %constantes de tiempo
            if max(y)>y(end) 
                Vector=stepinfo(y,t);
                Pico=Vector.PeakTime;
                Retardo=Vector.RiseTime;
                Asentamiento=Vector.SettlingTime;
                mp=Vector.Peak-y(end);
                coefamort=sqrt(1/((pi.^2/log(mp.^2))+1));
                wd=pi./Pico;
                wn=wd./sqrt(1-(coefamort)^2);
                
                [F_1,t]=step(FT,t);  
                
                %PARA ISE
                f1=y-F_1;
                ISE=trapz(f1.^2);
                
                %PARA ITSE
                f2=y-F_1;
                ITSE=trapz(t.*f2.^2);
                
                %PARA ITAE
                f3=y-F_1;
                ITAE=trapz(t.*abs(f3));
                
                set(app.KTextArea,'Value',num2str(y(end)));
                set(app.TauTextArea,'Value',num2str(tau));
                set(app.TmTextArea,'Value',num2str(tm));
                set(app.TextArea,'Value',FT_str); 
                set(app.TpTextArea,'Value',num2str(Pico));
                set(app.TsTextArea,'Value',num2str(Asentamiento));
                set(app.TrTextArea,'Value',num2str(Retardo));
                set(app.ISETextArea,'Value',num2str(ISE));
                set(app.ITSETextArea,'Value',num2str(ITSE));
                set(app.ITAETextArea,'Value',num2str(ITAE));
                set(app.WnTextArea,'Value',num2str(wn));
                set(app.AmortiguamientoTextArea,'Value',num2str(coefamort));
                set(app.WdTextArea,'Value',num2str(wd));
                plot(app.UIAxes2,t,y,t,F_1);
            else
                [F_1,t]=step(FT,t);  
                %PARA ISE
                f1=y-F_1;
                ISE=trapz(f1.^2);
                
                %PARA ITSE
                f2=y-F_1;
                ITSE=trapz(t.*f2.^2);
                
                %PARA ITAE
                f3=y-F_1;
                ITAE=trapz(t.*abs(f3));
                set(app.KTextArea,'Value',num2str(y(end)));
                set(app.TauTextArea,'Value',num2str(tau));
                set(app.TmTextArea,'Value',num2str(tm));
                set(app.TextArea,'Value',FT_str); 
                set(app.TpTextArea,'Value','NONE');
                set(app.TsTextArea,'Value','NONE');
                set(app.TrTextArea,'Value','NONE');
                set(app.ISETextArea,'Value',num2str(ISE));
                set(app.ITSETextArea,'Value',num2str(ITSE));
                set(app.ITAETextArea,'Value',num2str(ITAE));
                set(app.WnTextArea,'Value','NONE');
                set(app.AmortiguamientoTextArea,'Value','NONE');
                set(app.WdTextArea,'Value','NONE');
                plot(app.UIAxes2,t,y,t,F_1);
            end


        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            global F_1 t
            GUARDAR=F_1;
            GUARDAR=t;
            uisave({'GUARDAR'});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Respuesta')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Respuesta aproximada')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TextArea = uitextarea(app.UIFigure);
            app.CargarButton = uibutton(app.UIFigure, 'state');
            app.CargarButton.ValueChangedFcn = createCallbackFcn(app, @CargarButtonValueChanged, true);
            app.CargarButton.Text = 'Cargar';
            app.EjercutarButton = uibutton(app.UIFigure, 'state');
            app.EjercutarButton.ValueChangedFcn = createCallbackFcn(app, @EjercutarButtonValueChanged, true);
            app.EjercutarButton.Text = 'Ejercutar';
            app.FuncindetransferenciaLabel = uilabel(app.UIFigure);
            app.FuncindetransferenciaLabel.Text = 'Funciòn de transferencia';
            app.EscojaelmetodoquedeseesusarLabel = uilabel(app.UIFigure);
            app.EscojaelmetodoquedeseesusarLabel.Text = 'Escoja el metodo que desees usar';
            app.TipoTextAreaLabel = uilabel(app.UIFigure);
            app.TipoTextAreaLabel.Text = 'Tipo';
            app.TipoTextArea = uitextarea(app.UIFigure);
            app.TipoTextArea.Editable = 'off';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Alfaro', 'Jahamiri y Fallahi', 'Smith', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Alfaro';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
            app.KTextArea = uitextarea(app.UIFigure);
            app.KTextArea.Editable = 'off';
            app.TmLabel = uilabel(app.UIFigure);
            app.TmLabel.Text = 'Tm';
            app.TmTextArea = uitextarea(app.UIFigure);
            app.TmTextArea.Editable = 'off';
            app.tauLabel = uilabel(app.UIFigure);
            app.tauLabel.Text = 'Tau';
            app.TauTextArea = uitextarea(app.UIFigure);
            app.TauTextArea.Editable = 'off';
            app.AmortiguamientoLabel = uilabel(app.UIFigure);
            app.AmortiguamientoLabel.Text = 'Amortiguamiento';
            app.AmortiguamientoTextArea = uitextarea(app.UIFigure);
            app.AmortiguamientoTextArea.Editable = 'off';
            app.WnLabel = uilabel(app.UIFigure);
            app.WnLabel.Text = 'Wn';
            app.WnTextArea = uitextarea(app.UIFigure);
            app.WnTextArea.Editable = 'off';
            app.TpLabel = uilabel(app.UIFigure);
            app.TpLabel.Text = 'Tp';
            app.TpTextArea = uitextarea(app.UIFigure);
            app.TpTextArea.Editable = 'off';
            app.TsLabel = uilabel(app.UIFigure);
            app.TsLabel.Text = 'Ts';
            app.TsTextArea = uitextarea(app.UIFigure);
            app.TsTextArea.Editable = 'off';
            app.TrTextArea = uitextarea(app.UIFigure);
            app.TrTextArea.Editable = 'off';
            app.TrLabel = uilabel(app.UIFigure);
            app.TrLabel.Text = 'Tr';
            app.ISELabel = uilabel(app.UIFigure);
            app.ISELabel.Text = 'I S E';
            app.ITSELabel = uilabel(app.UIFigure);
            app.ITSELabel.Text = 'I T S E';
            app.ITAELabel = uilabel(app.UIFigure);
            app.ITAELabel.Text = 'I T A E';
            app.ISETextArea = uitextarea(app.UIFigure);
            app.ISETextArea.Editable = 'off';
            app.ITAETextArea = uitextarea(app.UIFigure);
            app.ITAETextArea.Editable = 'off';
            app.WdTextArea = uitextarea(app.UIFigure);
            app.WdTextArea.Editable = 'off';
            app.ITSETextArea = uitextarea(app.UIFigure);
            app.ITSETextArea.Editable = 'off';
            app.WdLabel = uilabel(app.UIFigure);
            app.WdLabel.Text = 'Wd';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ndicesdedesempeoLabel = uilabel(app.UIFigure);
            app.ndicesdedesempeoLabel.Text = 'índices de desempeño';
            app.ParmetrosdecaracterizacinLabel = uilabel(app.UIFigure);
            app.ParmetrosdecaracterizacinLabel.Text = 'Parámetros de caracterización';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplicacionmatlab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end