classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        SeleccioneuncontroladorDropDown  matlab.ui.control.DropDown
        delairekmhLabel                 matlab.ui.control.Label
        PerturbacionesVelocidadLabel    matlab.ui.control.Label
        VarianzaEditFieldLabel          matlab.ui.control.Label
        VarianzaEditField               matlab.ui.control.NumericEditField
        PromedioEditFieldLabel          matlab.ui.control.Label
        PromedioEditField               matlab.ui.control.NumericEditField
        TdEditFieldLabel                matlab.ui.control.Label
        TdEditField                     matlab.ui.control.NumericEditField
        TiEditFieldLabel                matlab.ui.control.Label
        TiEditField                     matlab.ui.control.NumericEditField
        kpEditFieldLabel                matlab.ui.control.Label
        kpEditField                     matlab.ui.control.NumericEditField
        ErrorTextArea                   matlab.ui.control.TextArea
        DropDown                        matlab.ui.control.DropDown
        MantenerGraficasAnterioresCheckBox  matlab.ui.control.CheckBox
        DenPlantaEditField              matlab.ui.control.EditField
        NumPlantaEditField              matlab.ui.control.EditField
        DenSensorEditField              matlab.ui.control.EditField
        NumSensorEditField              matlab.ui.control.EditField
        DenActuadorEditField            matlab.ui.control.EditField
        EjecutarButton                  matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        NumActuadorEditField            matlab.ui.control.EditField
        Image                           matlab.ui.control.Image
        UIAxesEntrada                   matlab.ui.control.UIAxes
        ConsignaUIAxes                  matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        ParmetrosDeCaracterizacinLabel  matlab.ui.control.Label
        FuncinDeTransferenciaLabel      matlab.ui.control.Label
        FTEstandarLabel                 matlab.ui.control.Label
        ParametrosTextArea              matlab.ui.control.TextArea
        FtEstandarTextArea              matlab.ui.control.TextArea
        FfTextArea                      matlab.ui.control.TextArea
        VariablesDeEstadoLabel          matlab.ui.control.Label
        cTextArea                       matlab.ui.control.TextArea
        dTextArea                       matlab.ui.control.TextArea
        bTextArea                       matlab.ui.control.TextArea
        aTextArea                       matlab.ui.control.TextArea
        UIAxesParametros                matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        CriteriodeRoothLabel            matlab.ui.control.Label
        EcuacincaracteristicaLabel      matlab.ui.control.Label
        RegindeestabilidadLabel         matlab.ui.control.Label
        roothTextArea                   matlab.ui.control.TextArea
        EstabilidadTextArea             matlab.ui.control.TextArea
        ecuacioncaracteristicaTextArea  matlab.ui.control.TextArea
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            act_num = str2num(app.NumActuadorEditField.Value) ;
            act_den = str2num(app.DenActuadorEditField.Value) ;
            plant_num = str2num(app.NumPlantaEditField.Value) ;
            plant_den = str2num(app.DenPlantaEditField.Value) ;
            sens_num = str2num(app.NumSensorEditField.Value) ;
            sens_den = str2num(app.DenSensorEditField.Value) ;
            meanv = (app.PromedioEditField.Value) ;
            varv = (app.VarianzaEditField.Value) ;
            k = (app.kpEditField.Value) ;
            ti=  (app.TiEditField.Value);
            td=  (app.TdEditField.Value);
            
            signal=app.DropDown.Value;
            switch signal 
                case 'Seleccione una Señal'
                    consigna=1;
                case 'Escalon'
                    consigna=1;
                case 'Rampa'
                    consigna=2;
                case 'Impulso'
                    consigna=3;
                case 'Parábola'
                    consigna=4;
            end

            controlador=app.SeleccioneuncontroladorDropDown.Value;
            switch controlador 
                case 'Seleccione el controlador'
                    k=0;
                    ki=0;
                    kd=0;
                    Kct=0;
                case 'Proporcional'
                    ki=0;
                    kd=0;
                    Kct=k;
                case 'Proporcional Integral'
                    ki=k/ti;
                    kd=0;
                    Kct=tf([k,ki],[1,0]);
                case 'Proporcional Derivativo'
                    ki=0;
                    kd=k*td;
                    Kct=tf([kd,k],[1]);
                
            end

            assignin("base","act_num",act_num);
            assignin("base","act_den",act_den);
            assignin("base","plant_num",plant_num);
            assignin("base","plant_den",plant_den);
            assignin("base","sens_num",sens_num);
            assignin("base","sens_den",sens_den);
            assignin("base","kp",k)
            assignin("base","ki",ki)
            assignin("base","kd",kd)
            assignin("base","meanv",meanv*0.001);
            assignin("base","varv",varv*0.001);
            assignin("base","consigna",consigna)


            %Funcion de transferencia
            plant_tf=tf(plant_num,plant_den);
            act_tf=tf(act_num,act_den);
            sens_tf=tf(sens_num,sens_den);
            Gs=act_tf*plant_tf;
            Hs=sens_tf;
            sys_tf=k*(Gs/(1+Gs*Hs));
            systxt = evalc("sys_tf");
            app.FfTextArea.Value = systxt;
            %Funcion de transferencia estandar
            minsys=minreal(sys_tf);
            minsystxt= evalc("minsys");
            app.FtEstandarTextArea.Value = minsystxt;

            hold(app.UIAxes, "off");
            hold(app.UIAxes, "off");
            hold(app.UIAxes, "off");
            sgrid(app.UIAxes, "new");
            syms K

            [p,z] = pzmap(minsys);
            realp = real(p);
            imagp = imag(p);
            realz = real(z);
            imagz = imag(z);
            minimun= [min(realz),min(realp)];
            [rl,kl] = rlocus(minsys, min(minimun):.0001:0);
            plot(app.UIAxes,kl,rl);
            hold(app.UIAxes, "on");
            plot(app.UIAxes,realz,imagz, 'o');
            hold(app.UIAxes, "on");
            plot(app.UIAxes,realp,imagp, 'x');
            hold(app.UIAxes, "on");


            %Parametros de caracterizacion
            stepinf=stepinfo(minsys);
            sysinftxt = evalc("stepinf");
            app.ParametrosTextArea.Value = sysinftxt;
            %Variables de estado
            [num,den]=tfdata(sys_tf,"v");
            [A B C D]=tf2ss(num,den);
            Atxt=evalc("A");
            app.aTextArea.Value = Atxt;
            Btxt=evalc("B");
            app.bTextArea.Value = Btxt;
            Ctxt=evalc("C");
            app.cTextArea.Value = Ctxt;
            Dtxt=evalc("D");
            app.dTextArea.Value = Dtxt;
            %Errores dependiendo de la entrada
            signaless=app.DropDown.Value;
            switch signaless 
                case 'Seleccione una Señal'
                    kp1=dcgain(sys_tf);
                    ess=1/(1+kp1);
                    esstxt = evalc("ess");
                    app.ErrorTextArea.Value = esstxt;
                case 'Escalon'
                    kp1=dcgain(sys_tf);
                    ess=1/(1+kp1);
                    esstxt = evalc("ess");
                    app.ErrorTextArea.Value = esstxt;
                case 'Rampa'
                    [num,den] = tfdata(sys_tf, 'v');
                    G1= tf(conv(num,[1 0]),den);
                    kv1=dcgain(G1);
                    ess1=1/kv1;
                    esstxt = evalc("ess1");
                    app.ErrorTextArea.Value = esstxt;
                case 'Impulso'
                    ess2=Inf;
                    esstxt = evalc("ess2");
                    app.ErrorTextArea.Value = esstxt;
                case 'Parábola'
                    [num,den] = tfdata(sys_tf, 'v');
                    G2= tf(conv(num,[1 0 0]),den);
                    ka1=dcgain(G2);
                    ess3=1/ka1;
                    esstxt = evalc("ess3");
                    app.ErrorTextArea.Value = esstxt;
            end
            %
            simout = sim("PI_model.slx");
            plot(app.ConsignaUIAxes, simout.consigna.Time, simout.consigna.Data)
            %
            holdplt=app.MantenerGraficasAnterioresCheckBox.Value;
            if holdplt
                hold(app.UIAxesEntrada, "on");
                hold(app.UIAxesParametros, "on");
                plot(app.UIAxesEntrada, simout.salida.Time, simout.salida.Data)
                legend(app.UIAxesEntrada)
                hold(app.UIAxesEntrada, "on");
                hold(app.UIAxesParametros, "on");
            else 
                hold(app.UIAxesEntrada, "off");
                hold(app.UIAxesParametros, "off");
                plot(app.UIAxesEntrada, simout.salida.Time, simout.salida.Data)
                legend(app.UIAxesEntrada)
                hold(app.UIAxesEntrada, "off");
                hold(app.UIAxesParametros, "off");
            end

            [y,t] = step(sys_tf);
            stepResults = stepinfo(y,t);
            tRise= stepResults.RiseTime;
            tSettle= stepResults.SettlingTime;
            minSettle= stepResults.SettlingMin;
            maxSettle= stepResults.SettlingMax;
            tPeak= stepResults.PeakTime;
            Peak= stepResults.Peak;
            
            indexRise = find(t >= tRise,1,'first');
            xRise = t(indexRise);
            yRise = y(indexRise);
            indexSettle = find(t >= tSettle,1,'first');
            xSettle = t(indexSettle);
            ySettle = y(indexSettle);
            indexPeak = find(t >= tPeak,1,'first');
            xPeak = t(indexPeak);
            yPeak = y(indexPeak);
            indexMinSettle = find(t >= minSettle,1,'first');
            xMinSettle = t(indexMinSettle);
            yMinSettle = y(indexMinSettle);
            indexMaxSettle = find(t >= maxSettle,1,'first');
            xMaxSettle = t(indexMaxSettle);
            yMaxSettle = y(indexMaxSettle);
            indexPeak1 = find(t >= Peak,1,'first');
            xPeak1 = t(indexPeak1);
            yPeak1 = y(indexPeak1);% Plot step response
            plot(app.UIAxesParametros, t,y)
            grid (app.UIAxesParametros,"on");
            hold(app.UIAxesParametros, "on");
            % Plot settling time point
            plot(app.UIAxesParametros,xRise,yRise,'ro');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,xSettle,ySettle,'go');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,xMinSettle,yMinSettle,'bo');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,xMaxSettle,yMaxSettle,'yo');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,xPeak,yPeak,'ko');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,xPeak1,yPeak1,'co');
            hold(app.UIAxesParametros, "on");
            % Plot chart lines to settling point
            plot(app.UIAxesParametros,[0 xSettle],[ySettle ySettle],'g-.');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,[xSettle xSettle],[0 ySettle],'g-.');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,[0 xRise],[yRise yRise],'r-.');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,[xRise xRise],[0 yRise],'r-.');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,[0 xPeak],[yPeak yPeak],'k-.');
            hold(app.UIAxesParametros, "on");
            plot(app.UIAxesParametros,[xPeak xPeak],[0 yPeak],'k-.');
            hold(app.UIAxesParametros, "on");


            controlstable=app.SeleccioneuncontroladorDropDown.Value;
            switch controlstable 
                case 'Seleccione el controlador'
                    GsHsNotK=0;
                case 'Proporcional'
                    GsHsNotK=act_tf*plant_tf*sens_tf;
                case 'Proporcional Integral'
                    CtrInt=tf([1 1/ti],[1 0]);
                    GsHsNotK=CtrInt*act_tf*plant_tf*sens_tf;
                case 'Proporcional Derivativo'
                    CtrDev=tf([td 1],[1]);
                    GsHsNotK=CtrDev*act_tf*plant_tf*sens_tf;
            end
            
            [NumEq, DenEq] = tfdata(GsHsNotK, 'v');
            Eq=DenEq+K*NumEq;
            
            Eqcaracteristica= evalc("Eq");
            app.ecuacioncaracteristicaTextArea.Value = Eqcaracteristica;
            
            m=length(Eq);
            n=round(m/2);
            zM=sym(zeros(m,n));
            j=1;
            for i=1:m
                if mod(i,2)==0
                    zM(2,j)=Eq(i);
                    j=j+1;
                else
                    zM(1,j)=Eq(i);
                end
            end
            for i=3:m
                for j=1:n-1
                    x=zM(i-1,1);
                    if x==0
                        x=eps;
                    end
                    zM(i,j)=((zM(i-1,1)*zM(i-2,j+1))-(zM(i-2,1)*zM(i-1,j+1)))/x;
                end
                if zM(i,:)==0
                    order=(m-i+1);
                    c=0;
                    d=1;
                    for j=1:n-1
                        zM(i,j)=(order-c)*(zM(i-1,d));
                        d=d+1;
                        c=c+2;
                    end
                end
              if zM(i,1)==0
                  zM(i,1)=eps;
              end
            end
            po=0;
            for i=1:m-1
                if sign(zM(i,1))*sign(zM(i+1,1))==-1
                    po=po+1;
                end
            end
            
            RootAnalysis= evalc("zM");
            app.roothTextArea.Value = RootAnalysis;
            
            EqinC = zM(:,1);
            Eqns = EqinC.';
            S = solve(Eqns>0,K, 'ReturnConditions', true);
            n=length(zM);
                for i=1:n
                    try
                        S = solve(Eqns(i)>0,K,'ReturnConditions',true);
                        if S.conditions==symtrue;
                            Z(i)="0 < x";
                        else
                            Z(i)=S.conditions;
                        end
                    catch
                        Z(i)=" - ";
                    end
                end
            Zcond=unique(Z);
            Conditions= Zcond;
            Cond= evalc("Conditions");
            app.EstabilidadTextArea.Value = Cond;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.ConsignaUIAxes = uiaxes(app.Tab);
            title(app.ConsignaUIAxes, 'Consigna')
            xlabel(app.ConsignaUIAxes, 'X')
            ylabel(app.ConsignaUIAxes, 'Y')
            zlabel(app.ConsignaUIAxes, 'Z')
            app.UIAxesEntrada = uiaxes(app.Tab);
            title(app.UIAxesEntrada, 'Respuesta En El Tiempo')
            xlabel(app.UIAxesEntrada, 'X')
            ylabel(app.UIAxesEntrada, 'Y')
            zlabel(app.UIAxesEntrada, 'Z')
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'BlockDiagram.png';
            app.NumActuadorEditField = uieditfield(app.Tab, 'text');
            app.NumActuadorEditField.Value = '[1]';
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageSource = 'download.jpeg';
            app.EjecutarButton = uibutton(app.Tab, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.DenActuadorEditField = uieditfield(app.Tab, 'text');
            app.DenActuadorEditField.Value = '[1 1]';
            app.NumSensorEditField = uieditfield(app.Tab, 'text');
            app.NumSensorEditField.Value = '[1]';
            app.DenSensorEditField = uieditfield(app.Tab, 'text');
            app.DenSensorEditField.Value = '[1 2]';
            app.NumPlantaEditField = uieditfield(app.Tab, 'text');
            app.NumPlantaEditField.Value = '[1]';
            app.DenPlantaEditField = uieditfield(app.Tab, 'text');
            app.DenPlantaEditField.Value = '[1 2 3]';
            app.MantenerGraficasAnterioresCheckBox = uicheckbox(app.Tab);
            app.MantenerGraficasAnterioresCheckBox.Text = 'Mantener Graficas Anteriores';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'Seleccione una Señal', 'Escalon', 'Rampa', 'Impulso', 'Parábola'};
            app.DropDown.Value = 'Seleccione una Señal';
            app.ErrorTextArea = uitextarea(app.Tab);
            app.kpEditField = uieditfield(app.Tab, 'numeric');
            app.kpEditFieldLabel = uilabel(app.Tab);
            app.kpEditFieldLabel.Text = 'kp';
            app.TiEditField = uieditfield(app.Tab, 'numeric');
            app.TiEditFieldLabel = uilabel(app.Tab);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TdEditField = uieditfield(app.Tab, 'numeric');
            app.TdEditFieldLabel = uilabel(app.Tab);
            app.TdEditFieldLabel.Text = 'Td';
            app.PromedioEditField = uieditfield(app.Tab, 'numeric');
            app.PromedioEditFieldLabel = uilabel(app.Tab);
            app.PromedioEditFieldLabel.Text = 'Promedio';
            app.VarianzaEditField = uieditfield(app.Tab, 'numeric');
            app.VarianzaEditFieldLabel = uilabel(app.Tab);
            app.VarianzaEditFieldLabel.Text = 'Varianza';
            app.PerturbacionesVelocidadLabel = uilabel(app.Tab);
            app.PerturbacionesVelocidadLabel.Text = 'Perturbaciones: Velocidad';
            app.delairekmhLabel = uilabel(app.Tab);
            app.delairekmhLabel.Text = 'del aire(km/h)';
            app.SeleccioneuncontroladorDropDown = uidropdown(app.Tab);
            app.SeleccioneuncontroladorDropDown.Items = {'Seleccione el controlador', 'Proporcional', 'Proporcional Integral', 'Proporcional Derivativo'};
            app.SeleccioneuncontroladorDropDown.Value = 'Seleccione el controlador';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxesParametros = uiaxes(app.Tab2);
            title(app.UIAxesParametros, 'Parametros')
            xlabel(app.UIAxesParametros, 'X')
            ylabel(app.UIAxesParametros, 'Y')
            zlabel(app.UIAxesParametros, 'Z')
            app.aTextArea = uitextarea(app.Tab2);
            app.bTextArea = uitextarea(app.Tab2);
            app.dTextArea = uitextarea(app.Tab2);
            app.cTextArea = uitextarea(app.Tab2);
            app.VariablesDeEstadoLabel = uilabel(app.Tab2);
            app.VariablesDeEstadoLabel.Text = 'Variables De Estado';
            app.FfTextArea = uitextarea(app.Tab2);
            app.FtEstandarTextArea = uitextarea(app.Tab2);
            app.ParametrosTextArea = uitextarea(app.Tab2);
            app.FTEstandarLabel = uilabel(app.Tab2);
            app.FTEstandarLabel.Text = 'FT Estandar';
            app.FuncinDeTransferenciaLabel = uilabel(app.Tab2);
            app.FuncinDeTransferenciaLabel.Text = ' Función De Transferencia';
            app.ParmetrosDeCaracterizacinLabel = uilabel(app.Tab2);
            app.ParmetrosDeCaracterizacinLabel.Text = 'Parámetros De Caracterización';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes = uiaxes(app.Tab3);
            title(app.UIAxes, 'Lugar de las raices')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ecuacioncaracteristicaTextArea = uitextarea(app.Tab3);
            app.EstabilidadTextArea = uitextarea(app.Tab3);
            app.roothTextArea = uitextarea(app.Tab3);
            app.RegindeestabilidadLabel = uilabel(app.Tab3);
            app.RegindeestabilidadLabel.Text = 'Región de estabilidad';
            app.EcuacincaracteristicaLabel = uilabel(app.Tab3);
            app.EcuacincaracteristicaLabel.Text = 'Ecuación caracteristica';
            app.CriteriodeRoothLabel = uilabel(app.Tab3);
            app.CriteriodeRoothLabel.Text = 'Criterio de Rooth';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end