classdef punto3lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KEditField                    matlab.ui.control.NumericEditField
        KEditFieldLabel               matlab.ui.control.Label
        Raices_2EditField             matlab.ui.control.NumericEditField
        Raices_2EditFieldLabel        matlab.ui.control.Label
        CasoaEvaluarTextArea          matlab.ui.control.TextArea
        CasoaEvaluarLabel             matlab.ui.control.Label
        TextArea_3                    matlab.ui.control.TextArea
        RangodeEstabilidadLabel       matlab.ui.control.Label
        AnlisisCriteriodeEstabilidaddeRouthLabel  matlab.ui.control.Label
        SolucinCriteriodeEstabilidadPanel  matlab.ui.container.Panel
        TextArea_5                    matlab.ui.control.TextArea
        InformacindelSistemaPanel     matlab.ui.container.Panel
        EjecutarAnlisisButton         matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        DigiteelpolinomioLabel        matlab.ui.control.Label
        AnlisisdelasRaicesPanel       matlab.ui.container.Panel
        TextArea_4                    matlab.ui.control.TextArea
        NmerodeRaicesEstablesLabel    matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        TextArea                      matlab.ui.control.TextArea
        NmerodeRaicesInestablesLabel  matlab.ui.control.Label
        RaicesdelsistemaLabel         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarAnlisisButton
        function EjecutarAnlisisButtonPushed(app, event)
global sys a
            syms k a ep iep
            sys=eval(app.EditField.Value);
            l=length(sys);
            n=round(l/2);
            a=sym(zeros(l,n));
            caso='Ningun caso';
            a(1,:)=sys(1,1:2:l);
                       
            %verifiquemos si el largo es par o impar
           if(rem(l,2) ~= 0)
               %si es impar
               a(2,1:n-1)=sys(1,2:2:l);
           else
               %si es par
               a(2,:)=sys(1,2:2:l);
               
           end
           
           %para el caso de la primera columna con 0's
for i=3:l
    
               if a(i-1,:) == 0
                   Caso='Fila llena de ceros'
                   A=(l-i);
                   c=0;
                   c2=1;
                   for j=1:n-1
                       a(i-1,j)=(A-c)*a(i-2,c2);
                       c2=c2+1;
                       c=c+2;
                   end
               end
               
                for j=1:n-1
                               if a(i-1,1)==0
                                   
                                  a(i-1,1)=ep;
                               end
                             firstElemUpperRow = a(i-1,1);
                               
                                 a(i,j) = ((firstElemUpperRow * a(i-2,j+1))-(a(i-2,1) * a(i-1,j+1))) / firstElemUpperRow;
                             
                end
               
           %caso especial, cero en la primer columna
           if a(i,1)== 0
               a(i,1)=ep;
               caso='Cero en la primera columna';
           end
end
a=simplify(expand(a));

a_sol=subs(a,ep,1/iep);
a_sol=simplify(expand(a_sol));
a_sol = subs(a_sol, iep, sym(inf));

%iniciando con los polos inestables en 0
inestables=0;

for i=1:l-1
    if sign(a_sol(i,1))*sign(a_sol(i+1,1)) == -1
        inestables=inestables+1;
    end
end
           RootAnalysis=evalc("a");
           app.TextArea_5.Value=RootAnalysis;
           
           EqinC=a(:,1);
           Eqns = EqinC.';
           S=solve(Eqns>0,k, 'ReturnConditions', true);
           n=length(a);
           for i=1:n
               try
           S=solve(Eqns(i)>0,k, 'ReturnConditions', true);
           if S.conditions==symtrue;
               Z(i)="0 < x";
           else
               Z(i)=S.conditions;
           end
           catch
               Z(i)="-";
               end
           end
            Zcond=unique(Z);
            conditions= Zcond;
            Cond= evalc("conditions");
            app.TextArea_3.Value = Cond;
            app.CasoaEvaluarTextArea.Value=caso;
            
            %para las raices
            raices=roots(sys);
            raices= vpa(raices, 3);
            k=app.KEditField.Value;
            realp = real(raices);
            imagp = imag(raices);
            realp=subs(realp,k);
            imagp=subs(imagp,k);
            sgrid(app.UIAxes, "new");
            plot(app.UIAxes,realp,imagp, 'xr');
            raices_v=evalc("raices");
            sysr = erase(raices_v,"raices =");
            sys2=str2sym(sysr)
            app.Raices_2EditField.Value=double(subs(sys2,k));
            app.TextArea.Value = sysr;
            
            unstable=find(raices>0);
            Ur=realp(unstable);
            NUr=length(Ur);
            stable=find(raices<=0);
            Sr=realp(stable);
            NSr=length(Sr);
            if NUr~=0
                app.TextArea_2.Value = num2str(NUr);
            else
                app.TextArea_2.Value = "No Existen";
            end
            if NSr~=0
                app.TextArea_4.Value = num2str(NSr);
            else
                app.TextArea_4.Value = "No Existen";
            end
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Lugar de las raices')
            xlabel(app.UIAxes, 'Eje Real (s^{-1})')
            ylabel(app.UIAxes, 'Eje imaginario (s^{-1})')
            app.AnlisisdelasRaicesPanel = uipanel(app.UIFigure);
            app.AnlisisdelasRaicesPanel.Title = 'Análisis de las Raices';
            app.RaicesdelsistemaLabel = uilabel(app.AnlisisdelasRaicesPanel);
            app.RaicesdelsistemaLabel.Text = 'Raices del sistema';
            app.NmerodeRaicesInestablesLabel = uilabel(app.AnlisisdelasRaicesPanel);
            app.NmerodeRaicesInestablesLabel.Text = 'Número de Raices Inestables';
            app.TextArea = uitextarea(app.AnlisisdelasRaicesPanel);
            app.TextArea_2 = uitextarea(app.AnlisisdelasRaicesPanel);
            app.NmerodeRaicesEstablesLabel = uilabel(app.AnlisisdelasRaicesPanel);
            app.NmerodeRaicesEstablesLabel.Text = 'Número de Raices Estables';
            app.TextArea_4 = uitextarea(app.AnlisisdelasRaicesPanel);
            app.InformacindelSistemaPanel = uipanel(app.UIFigure);
            app.InformacindelSistemaPanel.Title = 'Información del Sistema';
            app.DigiteelpolinomioLabel = uilabel(app.InformacindelSistemaPanel);
            app.DigiteelpolinomioLabel.Text = 'Digite el polinomio:';
            app.EditField = uieditfield(app.InformacindelSistemaPanel, 'text');
            app.EditField.Value = '[1 3 k 4]';
            app.EjecutarAnlisisButton = uibutton(app.InformacindelSistemaPanel, 'push');
            app.EjecutarAnlisisButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarAnlisisButtonPushed, true);
            app.EjecutarAnlisisButton.Text = 'Ejecutar Análisis';
            app.SolucinCriteriodeEstabilidadPanel = uipanel(app.UIFigure);
            app.SolucinCriteriodeEstabilidadPanel.Title = 'Solución Criterio de Estabilidad';
            app.TextArea_5 = uitextarea(app.SolucinCriteriodeEstabilidadPanel);
            app.AnlisisCriteriodeEstabilidaddeRouthLabel = uilabel(app.UIFigure);
            app.AnlisisCriteriodeEstabilidaddeRouthLabel.Text = 'Análisis Criterio de Estabilidad de Routh';
            app.RangodeEstabilidadLabel = uilabel(app.UIFigure);
            app.RangodeEstabilidadLabel.Text = 'Rango de Estabilidad ';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.CasoaEvaluarLabel = uilabel(app.UIFigure);
            app.CasoaEvaluarLabel.Text = 'Caso a Evaluar:';
            app.CasoaEvaluarTextArea = uitextarea(app.UIFigure);
            app.Raices_2EditFieldLabel = uilabel(app.UIFigure);
            app.Raices_2EditFieldLabel.Text = 'Raices_2';
            app.Raices_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = punto3lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end